classdef Copy_of_readjustFSR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Lamp                     matlab.ui.control.Lamp
        winsizeAfter_EditField   matlab.ui.control.NumericEditField
        winsizeBefore_EditField  matlab.ui.control.NumericEditField
        winsizeEditFieldLabel    matlab.ui.control.Label
        STDropDown_2             matlab.ui.control.DropDown
        STDropDown_2Label        matlab.ui.control.Label
        STDropDown               matlab.ui.control.DropDown
        STDropDownLabel          matlab.ui.control.Label
        PrtclEditField           matlab.ui.control.EditField
        PrtclEditFieldLabel      matlab.ui.control.Label
        SwpSpinner               matlab.ui.control.Spinner
        SwpSpinnerLabel          matlab.ui.control.Label
        ExportButton             matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        Slider_step6             matlab.ui.control.Slider
        Slider_step4             matlab.ui.control.Slider
        Slider_step2             matlab.ui.control.Slider
        UIAxes_step6_2           matlab.ui.control.UIAxes
        UIAxes_step4_2           matlab.ui.control.UIAxes
        UIAxes_step2_2           matlab.ui.control.UIAxes
        UIAxes_step4             matlab.ui.control.UIAxes
        UIAxes_step2             matlab.ui.control.UIAxes
        UIAxes_step6             matlab.ui.control.UIAxes
        UIAxes_all               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % Description
        type
        plt2
        plt1 % = 3
        FSR % = 4
        proto % 
        step_index % Description
        sweep % Description
        rise2
        rise4
        rise6
        xaxis
        SOL
        ANG
        VEL
        ACC
        winBefore
        winAfter
    end
    
    methods (Access = private)
        
        function results = fcn_updatePlots(app)
            %app.fcn_updateIndexs()

            app.fcn_updateLamp()
           
            % plot properties 
            color_rise = "black"; 
            win_size_after  = app.winAfter; 
            win_size_before = app.winBefore; 

            % Step 2, Upper plots 
            window = app.rise2-win_size_before:app.rise2+win_size_after;          
            yyaxis(app.UIAxes_step2,'left')
            hold(app.UIAxes_step2, "off")
            plot(app.UIAxes_step2, app.xaxis(window), app.data{app.proto,app.plt1}(app.sweep, window))
            app.UIAxes_step2.YLabel.String = app.fcn_updataLabel(app.plt1);
            hold(app.UIAxes_step2, "on")
            YL(1) = min(app.data{app.proto,app.plt1}(app.sweep, window));
            YL(2) = max(app.data{app.proto,app.plt1}(app.sweep, window));
            plot(app.UIAxes_step2, [app.xaxis(app.rise2), app.xaxis(app.rise2)], [YL(1) YL(2)], "color", color_rise)

            yyaxis(app.UIAxes_step2,'right')
            hold(app.UIAxes_step2, "off")
            plot(app.UIAxes_step2, app.xaxis(window), app.data{app.proto,app.FSR}(app.sweep,window))
            axis(app.UIAxes_step2, 'padded')

            % Step 2, Lower plots
            yyaxis(app.UIAxes_step2_2,'left')
            hold(app.UIAxes_step2_2, "off")
            plot(app.UIAxes_step2_2, app.xaxis(window), app.data{app.proto,app.plt2}(app.sweep, window))
            app.UIAxes_step2_2.YLabel.String = app.fcn_updataLabel(app.plt2);

            hold(app.UIAxes_step2_2, "on")
            YL(1) = min(app.data{app.proto,app.plt2}(app.sweep, window));
            YL(2) = max(app.data{app.proto,app.plt2}(app.sweep, window));
            plot(app.UIAxes_step2_2, [app.xaxis(app.rise2), app.xaxis(app.rise2)], [YL(1) YL(2)], "color", color_rise)
            plot(app.UIAxes_step2_2, [app.xaxis(app.rise2)+39, app.xaxis(app.rise2)+39], [YL(1) YL(2)], "color", color_rise)

            yyaxis(app.UIAxes_step2_2,'right')
            hold(app.UIAxes_step2_2, "off")
            plot(app.UIAxes_step2_2, app.xaxis(window), app.data{app.proto,app.FSR}(app.sweep,window))
            axis(app.UIAxes_step2_2, 'padded')
            

            % step 4, Upper plots 
            window = app.rise4-win_size_before:app.rise4+win_size_after; 
            yyaxis(app.UIAxes_step4,'left')
            hold(app.UIAxes_step4, "off")
            plot(app.UIAxes_step4, app.xaxis(window), app.data{app.proto,app.plt1}(app.sweep, window))
            hold(app.UIAxes_step4, "on")
            YL(1) = min(app.data{app.proto,app.plt1}(app.sweep, window));
            YL(2) = max(app.data{app.proto,app.plt1}(app.sweep, window));
            plot(app.UIAxes_step4, [app.xaxis(app.rise4), app.xaxis(app.rise4)], [YL(1) YL(2)], "color", color_rise)
            
            yyaxis(app.UIAxes_step4,'right')
            hold(app.UIAxes_step4, "off")
            plot(app.UIAxes_step4, app.xaxis(window), app.data{app.proto,app.FSR}(app.sweep,window))
            axis(app.UIAxes_step4, 'padded')

            % step 4, Lower plots 
            yyaxis(app.UIAxes_step4_2,'left')
            hold(app.UIAxes_step4_2, "off")
            plot(app.UIAxes_step4_2, app.xaxis(window), app.data{app.proto,app.plt2}(app.sweep, window))
            hold(app.UIAxes_step4_2, "on")
            YL(1) = min(app.data{app.proto,app.plt2}(app.sweep, window));
            YL(2) = max(app.data{app.proto,app.plt2}(app.sweep, window));
            plot(app.UIAxes_step4_2, [app.xaxis(app.rise4), app.xaxis(app.rise4)], [YL(1) YL(2)], "color", color_rise)
            plot(app.UIAxes_step4_2, [app.xaxis(app.rise4)+39, app.xaxis(app.rise4)+39], [YL(1) YL(2)], "color", color_rise)

            
            yyaxis(app.UIAxes_step4_2,'right')
            hold(app.UIAxes_step4_2, "off")
            plot(app.UIAxes_step4_2, app.xaxis(window), app.data{app.proto,app.FSR}(app.sweep,window))
            axis(app.UIAxes_step4_2, 'padded')

            % Step 6, Upper plots
            window = app.rise6-win_size_before:app.rise6+win_size_after; 
            yyaxis(app.UIAxes_step6,'left')
            hold(app.UIAxes_step6, "off")
            plot(app.UIAxes_step6, app.xaxis(window), app.data{app.proto,app.plt1}(app.sweep, window))
            hold(app.UIAxes_step6, "on")
            YL(1) = min(app.data{app.proto,app.plt1}(app.sweep, window));
            YL(2) = max(app.data{app.proto,app.plt1}(app.sweep, window));
            plot(app.UIAxes_step6, [app.xaxis(app.rise6), app.xaxis(app.rise6)], [YL(1) YL(2)], "color", color_rise)
            
            yyaxis(app.UIAxes_step6,'right')
            hold(app.UIAxes_step6, "off")
            plot(app.UIAxes_step6, app.xaxis(window), app.data{app.proto,app.FSR}(app.sweep,window))
            axis(app.UIAxes_step6, 'padded')

            % Step 6, Lower plotfs
            yyaxis(app.UIAxes_step6_2,'left')
            hold(app.UIAxes_step6_2, "off")
            plot(app.UIAxes_step6_2, app.xaxis(window), app.data{app.proto,app.plt2}(app.sweep, window))
            hold(app.UIAxes_step6_2, "on")
            YL(1) = min(app.data{app.proto,app.plt2}(app.sweep, window));
            YL(2) = max(app.data{app.proto,app.plt2}(app.sweep, window));
            plot(app.UIAxes_step6_2, [app.xaxis(app.rise6), app.xaxis(app.rise6)], [YL(1) YL(2)], "color", color_rise)
            plot(app.UIAxes_step6_2, [app.xaxis(app.rise6)+39, app.xaxis(app.rise6)+39], [YL(1) YL(2)], "color", color_rise)

            yyaxis(app.UIAxes_step6_2,'right')
            hold(app.UIAxes_step6_2, "off")
            plot(app.UIAxes_step6_2, app.xaxis(window), app.data{app.proto,app.FSR}(app.sweep,window))
            axis(app.UIAxes_step6_2, 'padded')

            % All steps 
            yyaxis(app.UIAxes_all,'left')   % Left plotting
            hold(app.UIAxes_all, "off")     % Reset plot
            app.UIAxes_all.YLabel.String = app.fcn_updataLabel(app.plt1);  % Ylabel
            plot(app.UIAxes_all, app.xaxis, app.data{app.proto,app.plt1}(app.sweep,:)) 
            hold(app.UIAxes_all, "on") 
            YL(1) = min(app.data{app.proto,app.plt1}(app.sweep, :));
            YL(2) = max(app.data{app.proto,app.plt1}(app.sweep, :));
            plot(app.UIAxes_all, [app.xaxis(app.rise2), app.xaxis(app.rise2)], [YL(1) YL(2)], "color", color_rise)
            plot(app.UIAxes_all, [app.xaxis(app.rise4), app.xaxis(app.rise4)], [YL(1) YL(2)], "color", color_rise)
            plot(app.UIAxes_all, [app.xaxis(app.rise6), app.xaxis(app.rise6)], [YL(1) YL(2)], "color", color_rise)

            yyaxis(app.UIAxes_all,'right')
            hold(app.UIAxes_all, "off")
            plot(app.UIAxes_all, app.xaxis, app.data{app.proto,app.FSR}(app.sweep,:))
            axis(app.UIAxes_all, 'padded')

        end
        
        function fcn_updateLamp(app)
            if app.proto == 1
                lamp_status = "w"; 
            elseif app.proto == 2  %VER
                if ismember(app.sweep, app.type{1})
                    lamp_status = "g"; 
                else 
                    lamp_status = "y"; 
                end
            elseif app.proto == 3  %VER
                if ismember(app.sweep, app.type{3})
                    lamp_status = "g"; 
                else 
                    lamp_status = "y"; 
                end 
            end 
            app.Lamp.Color = lamp_status; 
        end

        function result = fcn_updataLabel(app, input)
            if input == app.SOL
                result = 'Soleus'; 
            elseif input == app.ANG
                result = 'Position'; 
            elseif input == app.VEL
                result = 'Velocity'; 
            elseif input == app.ACC
                result = 'Acceleration'; 
            end
        end

%            app.SOL = 1; app.ANG = 3; app.FSR = 4; app.VEL = 6; app.ACC = 7; app.sweep = 1; 


        function fcn_updateIndexs(app)
            % updates field values:
            app.EditField_step2.Value = app.xaxis(app.rise2); 
            app.EditField_step4.Value = app.xaxis(app.rise4);
            app.EditField_step6.Value = app.xaxis(app.rise6);
        end
        
        function fcn_loadRise(app)
            % Update step_index to set values.
            
            % update step 2
            [rise] = func_find_edge(2); 
            app.rise2 = app.step_index{app.proto}(app.sweep,rise) ; 
            
            % update step 4
            [rise] = func_find_edge(4); 
            app.rise4 = app.step_index{app.proto}(app.sweep,rise); 
           
            % update step 6
            [rise] = func_find_edge(6); 
            app.rise6 = app.step_index{app.proto}(app.sweep,rise); 
        end
        
        function fcn_saveRise(app)
            [rise] = func_find_edge(2); 
            app.step_index{app.proto}(app.sweep,rise) = app.rise2; 
            % update step 4
            [rise] = func_find_edge(4); 
            app.step_index{app.proto}(app.sweep,rise) = app.rise4; 
            % update step 6
            [rise] = func_find_edge(6); 
            app.step_index{app.proto}(app.sweep,rise) = app.rise6; 
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.SOL = 1; app.ANG = 3; app.FSR = 4; app.VEL = 6; app.ACC = 7; app.sweep = 1; 
            app.xaxis = linspace(-4000,6000,20000); 
            app.winAfter = app.winsizeAfter_EditField.Value; 
            app.winBefore = app.winsizeBefore_EditField.Value; 
            app.plt2 = app.SOL;
            app.plt1 = app.ANG; 

            crt_proto = true; 
            switch app.PrtclEditField.Value
                case "CTL"
                    app.proto = 1; 
                case "VER"
                    app.proto = 2; 
                case "HOR"
                    app.proto = 3;
                otherwise
                    opts = struct('WindowStyle','modal','Interpreter','tex');
                    errordlg('Unknown input in protocol text field. Valuable options: [CTL, VER, HOR]','User Error', opts);
                    crt_proto = false; 
            end 
            
            if crt_proto == true
                app.data = evalin('base','data');   % load data from workspace 
                app.step_index = evalin('base', 'step_index');  % load foot placement from workspace 
                app.type = evalin('base','type');   % load data from workspace 

                app.fcn_loadRise()                  % load new rise values
                app.fcn_updatePlots()               % plot data 
            end 
            
        end

        % Value changed function: SwpSpinner
        function SwpSpinnerValueChanged(app, event)
            value = app.SwpSpinner.Value;

            if size(app.data{app.proto,app.plt1},1) < value
                app.SwpSpinner.Value = size(app.data{app.proto,app.plt1},1); 
            elseif 1 > value 
                app.SwpSpinner.Value = 1; 
            else % new sweep 
                app.fcn_saveRise()                  % save rise values 
                app.sweep = app.SwpSpinner.Value; % update sweep num
                app.fcn_loadRise()                  % load new rise values
                app.fcn_updatePlots()               % plot data
            end
        end

        % Callback function
        function EditField_step2ValueChanged(app, event)
            value = app.EditField_step2.Value;
            value = find(value == app.xaxis ); 
            if or(value <= 0, value >= 20000-app.win_size ) 
                errordlg("error")
                app.EditField_step2.Value = app.xaxis(app.rise2); 
            else
                app.rise2 = value; 
                app.fcn_updatePlots()
            end
        end

        % Value changed function: Slider_step2
        function Slider_step2ValueChanged(app, event)
            app.rise2 = ceil(app.rise2 + app.Slider_step2.Value);
            app.Slider_step2.Value = 0; 
            
            app.fcn_updatePlots()
        end

        % Value changed function: Slider_step4
        function Slider_step4ValueChanged(app, event)
            app.rise4 = ceil(app.rise4 + app.Slider_step4.Value);
            app.Slider_step4.Value = 0; 
           
            app.fcn_updatePlots()
        end

        % Value changed function: Slider_step6
        function Slider_step6ValueChanged(app, event)
            app.rise6 = ceil(app.rise6 + app.Slider_step6.Value);
            app.Slider_step6.Value = 0; 
           
            app.fcn_updatePlots()            
        end

        % Callback function
        function EditField_step4ValueChanged(app, event)
            value = app.EditField_step4.Value;
            value = find(value == app.xaxis ); 

            if or(value <= 0, value >= 20000-app.win_size ) 
                errordlg("error")
                app.EditField_step4.Value = app.xaxis(app.rise4); 
            else
                app.rise4 = value; 
                app.fcn_updatePlots()
            end            

        end

        % Callback function
        function EditField_step6ValueChanged(app, event)
            value = app.EditField_step6.Value;
            value = find(value == app.xaxis );
            if or(value <= 0, value >= 20000-app.win_size ) 
                errordlg("error")
                app.EditField_step6.Value = app.xaxis(app.rise6); 
            else
                app.rise6 = value; 
                app.fcn_updatePlots()
            end            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            assignin('base','offset',app.step_index)
        end

        % Value changed function: PrtclEditField
        function PrtclEditFieldValueChanged(app, event)
            crt_proto = true; 
            switch app.PrtclEditField.Value
                case "CTL"
                    app.proto = 1; 
                case "VER"
                    app.proto = 2; 
                case "HOR"
                    app.proto = 3;
                otherwise
                    opts = struct('WindowStyle','modal','Interpreter','tex');
                    errordlg('Unknown input in protocol text field. Valuable options: [CTL, VER, HOR]','User Error', opts);
                    crt_proto = false; 
            end 
            
            if crt_proto == true
                app.sweep = 1; 
                app.SwpSpinner.Value = app.sweep;
                app.fcn_loadRise()                  % load new rise values
                app.fcn_updatePlots()               % plot data 
                
            else
                switch app.proto
                    case 1
                        app.PrtclEditField.Value = "CTL";
                    case 2
                        app.PrtclEditField.Value = "VER";
                    case 3
                        app.PrtclEditField.Value = "HOR";
                end                       
            end
        end

        % Value changed function: STDropDown
        function STDropDownValueChanged(app, event)
            value = app.STDropDown.Value;
            switch value
                case "SOL"
                    app.plt2 = app.SOL;  
                case "ANG"
                    app.plt2 = app.ANG;  
                case "ACC"
                    app.plt2 = app.ACC;  
                case "VEL"
                    app.plt2 = app.VEL; 
            end               
            app.fcn_updatePlots()               % plot data 
        end

        % Value changed function: STDropDown_2
        function STDropDown_2ValueChanged(app, event)
            value = app.STDropDown_2.Value;
            switch value
                case "SOL"
                    app.plt1 = app.SOL;  
                case "ANG"
                    app.plt1 = app.ANG;  
                case "ACC"
                    app.plt1 = app.ACC; 
                case "VEL"
                    app.plt1 = app.VEL;
            end   
            app.fcn_updatePlots()               % plot data 
        end

        % Value changed function: winsizeBefore_EditField
        function winsizeBefore_EditFieldValueChanged(app, event)
            value = app.winsizeBefore_EditField.Value;
            app.winBefore = value; 
            app.fcn_updatePlots()               % plot data 

        end

        % Value changed function: winsizeAfter_EditField
        function winsizeAfter_EditFieldValueChanged(app, event)
            value = app.winsizeAfter_EditField.Value;
            app.winAfter = value; 
            app.fcn_updatePlots()               % plot data 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_all = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_all, 'time [ms]')
            ylabel(app.UIAxes_all, 'Position')
            zlabel(app.UIAxes_all, 'Z')
            app.UIAxes_step6 = uiaxes(app.UIFigure);
            title(app.UIAxes_step6, 'Step 6')
            zlabel(app.UIAxes_step6, 'Z')
            app.UIAxes_step2 = uiaxes(app.UIFigure);
            title(app.UIAxes_step2, 'Step 2')
            ylabel(app.UIAxes_step2, 'Position')
            zlabel(app.UIAxes_step2, 'Z')
            app.UIAxes_step4 = uiaxes(app.UIFigure);
            title(app.UIAxes_step4, 'Step 4')
            zlabel(app.UIAxes_step4, 'Z')
            app.UIAxes_step2_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_step2_2, 'time [ms]')
            ylabel(app.UIAxes_step2_2, 'Soleus')
            zlabel(app.UIAxes_step2_2, 'Z')
            app.UIAxes_step4_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_step4_2, 'time [ms]')
            zlabel(app.UIAxes_step4_2, 'Z')
            app.UIAxes_step6_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_step6_2, 'time [ms]')
            zlabel(app.UIAxes_step6_2, 'Z')
            app.Slider_step2 = uislider(app.UIFigure);
            app.Slider_step2.Limits = [-80 80];
            app.Slider_step2.ValueChangedFcn = createCallbackFcn(app, @Slider_step2ValueChanged, true);
            app.Slider_step4 = uislider(app.UIFigure);
            app.Slider_step4.Limits = [-80 80];
            app.Slider_step4.ValueChangedFcn = createCallbackFcn(app, @Slider_step4ValueChanged, true);
            app.Slider_step6 = uislider(app.UIFigure);
            app.Slider_step6.Limits = [-80 80];
            app.Slider_step6.ValueChangedFcn = createCallbackFcn(app, @Slider_step6ValueChanged, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SwpSpinnerLabel = uilabel(app.UIFigure);
            app.SwpSpinnerLabel.Text = 'Swp';
            app.SwpSpinner = uispinner(app.UIFigure);
            app.SwpSpinner.ValueChangedFcn = createCallbackFcn(app, @SwpSpinnerValueChanged, true);
            app.SwpSpinner.Value = 1;
            app.PrtclEditFieldLabel = uilabel(app.UIFigure);
            app.PrtclEditFieldLabel.Text = 'Prtcl';
            app.PrtclEditField = uieditfield(app.UIFigure, 'text');
            app.PrtclEditField.ValueChangedFcn = createCallbackFcn(app, @PrtclEditFieldValueChanged, true);
            app.PrtclEditField.Value = 'CTL';
            app.STDropDownLabel = uilabel(app.UIFigure);
            app.STDropDownLabel.Text = 'ST';
            app.STDropDown = uidropdown(app.UIFigure);
            app.STDropDown.Items = {'SOL', 'VEL', 'ANG', 'ACC'};
            app.STDropDown.ValueChangedFcn = createCallbackFcn(app, @STDropDownValueChanged, true);
            app.STDropDown.Value = 'SOL';
            app.STDropDown_2Label = uilabel(app.UIFigure);
            app.STDropDown_2Label.Text = 'ST';
            app.STDropDown_2 = uidropdown(app.UIFigure);
            app.STDropDown_2.Items = {'ANG', 'VEL', 'SOL', 'ACC'};
            app.STDropDown_2.ValueChangedFcn = createCallbackFcn(app, @STDropDown_2ValueChanged, true);
            app.STDropDown_2.Value = 'ANG';
            app.winsizeEditFieldLabel = uilabel(app.UIFigure);
            app.winsizeEditFieldLabel.Text = 'win size';
            app.winsizeBefore_EditField = uieditfield(app.UIFigure, 'numeric');
            app.winsizeBefore_EditField.ValueChangedFcn = createCallbackFcn(app, @winsizeBefore_EditFieldValueChanged, true);
            app.winsizeBefore_EditField.Value = 300;
            app.winsizeAfter_EditField = uieditfield(app.UIFigure, 'numeric');
            app.winsizeAfter_EditField.ValueChangedFcn = createCallbackFcn(app, @winsizeAfter_EditFieldValueChanged, true);
            app.winsizeAfter_EditField.Value = 900;
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_readjustFSR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end