classdef findStepOnFSR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        SweepSpinner            matlab.ui.control.Spinner
        SweepSpinnerLabel       matlab.ui.control.Label
        RiseSpinner7            matlab.ui.control.Spinner
        RiseSpinner_4Label      matlab.ui.control.Label
        FallSpinner6            matlab.ui.control.Spinner
        FallSpinner_3Label      matlab.ui.control.Label
        RiseSpinner6            matlab.ui.control.Spinner
        RiseSpinner_3Label      matlab.ui.control.Label
        FallSpinner4            matlab.ui.control.Spinner
        FallSpinner_2Label      matlab.ui.control.Label
        RiseSpinner4            matlab.ui.control.Spinner
        RiseSpinner_2Label      matlab.ui.control.Label
        FallSpinner2            matlab.ui.control.Spinner
        FallSpinnerLabel        matlab.ui.control.Label
        RiseSpinner2            matlab.ui.control.Spinner
        RiseSpinnerLabel        matlab.ui.control.Label
        LoadButton              matlab.ui.control.Button
        ExportButton            matlab.ui.control.Button
        ProtocolEditField       matlab.ui.control.EditField
        ProtocolEditFieldLabel  matlab.ui.control.Label
        Step7Label              matlab.ui.control.Label
        Step6Label              matlab.ui.control.Label
        Step4Label              matlab.ui.control.Label
        Step2Label              matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
        ContextMenu             matlab.ui.container.ContextMenu
        Menu                    matlab.ui.container.Menu
        Menu2                   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        ANG
        FSR
        sweep
        proto
        data
        step_index

        rise2
        fall2
        rise4
        fall4
        rise6
        fall6
        rise7
        xaxis
   
        edges
        edgesNum

        
    end
    
    methods (Access = private)
        
        function fcn_updatePlots(app)
          
            % plot properties 
            color_rise = "blue"; 
            color_fall = "red"; 
            color_FSR = "black";
            linewidthRF = 2; 
            linestyleRF = '--'; 
            markerRF = '.'; 
            numVertical = 0; 
            numHorizontal = 0.02; 
            frontsizeNum = 16; 

           % all steps 
            yyaxis(app.UIAxes,'left')
            title(app.UIAxes, "Sweep " + num2str(app.sweep))
            hold(app.UIAxes, "off")
            plot(app.UIAxes, app.xaxis, app.data{app.proto,app.ANG}(app.sweep,:), 'LineWidth',2.5)


            YL(1) = min(app.data{app.proto,app.ANG}(app.sweep, :));
            YL(2) = max(app.data{app.proto,app.ANG}(app.sweep, :));
            x1 = [app.xaxis(app.edges(1)) app.xaxis(app.edges(2)) app.xaxis(app.edges(2)) app.xaxis(app.edges(1))];
            x2 = [app.xaxis(app.edges(3)) app.xaxis(app.edges(4)) app.xaxis(app.edges(4)) app.xaxis(app.edges(3))];
            x3 = [app.xaxis(app.edges(5)) app.xaxis(app.edges(6)) app.xaxis(app.edges(6)) app.xaxis(app.edges(5))];
            y = [YL(1) YL(1) YL(2) YL(2)];
            patch(app.UIAxes, x1, y,'red', 'FaceAlpha',.1)

            hold(app.UIAxes, "on")

            patch(app.UIAxes, x2, y,'green','FaceAlpha',.1)
            patch(app.UIAxes, x3, y,'magenta','FaceAlpha',.1)

            plot(app.UIAxes, app.xaxis, app.data{app.proto,app.ANG}(app.sweep,:), 'LineWidth',2.5)


            text(app.UIAxes, app.xaxis(app.edges(1)),YL(2)-1,"Step 2")
            text(app.UIAxes, app.xaxis(app.edges(3)),YL(2)-1,"Step 4")
            text(app.UIAxes, app.xaxis(app.edges(5)),YL(2)-1,"Step 6")


            for i = 1:7
                text(app.UIAxes, app.xaxis(app.edges(i))+numHorizontal,YL(1)-numVertical, num2str(app.edgesNum(i)), 'FontSize',frontsizeNum)
            end

            plot(app.UIAxes, [app.xaxis(app.edges(1)), app.xaxis(app.edges(1))], [YL(1) YL(2)], "color", color_rise, 'LineWidth', linewidthRF, 'LineStyle',linestyleRF, 'Marker',markerRF)
            plot(app.UIAxes, [app.xaxis(app.edges(3)), app.xaxis(app.edges(3))], [YL(1) YL(2)], "color", color_rise, 'LineWidth', linewidthRF, 'LineStyle',linestyleRF, 'Marker',markerRF)
            plot(app.UIAxes, [app.xaxis(app.edges(5)), app.xaxis(app.edges(5))], [YL(1) YL(2)], "color", color_rise, 'LineWidth', linewidthRF, 'LineStyle',linestyleRF, 'Marker',markerRF)
            plot(app.UIAxes, [app.xaxis(app.edges(7)), app.xaxis(app.edges(7))], [YL(1) YL(2)], "color", color_rise, 'LineWidth', linewidthRF, 'LineStyle',linestyleRF, 'Marker',markerRF)

            plot(app.UIAxes, [app.xaxis(app.edges(2)), app.xaxis(app.edges(2))], [YL(1) YL(2)], "color", color_fall, 'LineWidth', linewidthRF, 'LineStyle',linestyleRF, 'Marker',markerRF)
            plot(app.UIAxes, [app.xaxis(app.edges(4)), app.xaxis(app.edges(4))], [YL(1) YL(2)], "color", color_fall, 'LineWidth', linewidthRF, 'LineStyle',linestyleRF, 'Marker',markerRF)
            plot(app.UIAxes, [app.xaxis(app.edges(6)), app.xaxis(app.edges(6))], [YL(1) YL(2)], "color", color_fall, 'LineWidth', linewidthRF, 'LineStyle',linestyleRF, 'Marker',markerRF)

            yyaxis(app.UIAxes,'right')
            hold(app.UIAxes, "off")
            plot(app.UIAxes, app.xaxis, app.data{app.proto,app.FSR}(app.sweep,:), "color", color_FSR)
            axis(app.UIAxes, 'padded')
        end
        
        function fcn_updateNumbersAllowed(app, input, dir )
            edges_index_total = find(edge(app.data{app.proto, app.FSR}(app.sweep,:)))+1; %  % Find all edge pos in FSR signal
            total_edges = length(edges_index_total);
            

            % Move array in direction of spinner choose
            if dir == 1
                count = false;
                while count == false
                    for i = 1:7
                        y = app.edgesNum(input)+(dir*i);
                        if y > total_edges
                            y = y - total_edges; 
                        end

                        if ~(y == app.edgesNum(:))
                            % if no match. The move distance has been
                            % found
                            move = i; 
                            count = true; 
                            break
                        end
                    end
                    pos = [input:7, 1:input-1];
                    app.edgesNum(pos(1:move)) = app.edgesNum(pos(1:move)) + 1;
                end
            else % dir = -1
                count = false; 
                while count == false
                    for i = 1:7
                        y = app.edgesNum(input)+(dir*i);
                        if y < 1
                            y = y + total_edges; 
                        end
                        if ~(y == app.edgesNum(:))
                            % if no match. The move distance has been
                            % found
                            move = i; 
                            count = true; 
                            break
                        end
                    end
                    temp = linspace(7,input, length(input:7)); 
                    pos = [linspace(input,1,length(1:input)), temp(1:end-1)];
                    app.edgesNum(pos(1:move)) = app.edgesNum(pos(1:move)) - 1;
                end
            end 


            % Check if outher bounch 
            for i = 1:7
                if app.edgesNum(i) > total_edges
                    app.edgesNum(i) = 1; 
                elseif app.edgesNum(i) < 1
                    app.edgesNum(i) = total_edges; 
                end 
            end

            
            for i = 1:7
                % update step to new position
                app.step_index{app.proto}(app.sweep,7-i+1) = edges_index_total(app.edgesNum(i));

                % Yes i know it stupid dont wanna change now 
                app.edges(i) = app.step_index{app.proto}(app.sweep,7-i+1) ; 
            end

            % flip(app.step_index{app.proto}(app.sweep,:))
            % app.edgesNum
            % edges_index_total

            app.fcn_updateFields()
            app.fcn_updatePlots() 
        end

        % Names that make sense ... no
        function fcn_indexes(app)
            edges_index_total = find(edge(app.data{app.proto, app.FSR}(app.sweep,:)));
            
            for i = 1:7
                if app.step_index{app.proto}(app.sweep,7-i+1) == 0
                    app.step_index{app.proto}(app.sweep,7-i+1) = edges_index_total(5);
                end
                app.edges(i) = app.step_index{app.proto}(app.sweep,7-i+1) ; % step 2
            end

            for i = 1:7
                if find(app.edges(i) == edges_index_total)
                    x = 0; 
                elseif find(app.edges(i) == edges_index_total+1)
                    x = 1; 
                elseif find(app.edges(i) == edges_index_total-1)
                    x = -1; 
                end
                app.edgesNum(i) = find(app.edges(i) == edges_index_total+x);
            end
    
            app.fcn_updateFields()
         end

         function fcn_updateFields(app)
            app.RiseSpinner2.Value = app.edgesNum(1); % step 2
            app.FallSpinner2.Value = app.edgesNum(2);
            app.RiseSpinner4.Value = app.edgesNum(3); % step 4
            app.FallSpinner4.Value = app.edgesNum(4);
            app.RiseSpinner6.Value = app.edgesNum(5); % step 6
            app.FallSpinner6.Value = app.edgesNum(6);
            app.RiseSpinner7.Value = app.edgesNum(7); % step 7
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.ANG = 3; app.FSR = 4;

            correct_protocol_input = true; 
            switch app.ProtocolEditField.Value
                case "CTL"
                    app.proto = 1; 
                case "VER"
                    app.proto = 2; 
                case "HOR"
                    app.proto = 3;
                otherwise
                    opts = struct('WindowStyle','modal','Interpreter','tex');
                    errordlg('Unknown input in protocol text field. Valuable options: [CTL, VER, HOR]','User Error', opts);
                    correct_protocol_input = false; 
            end 
            
            if correct_protocol_input == true
                app.data = evalin('base','data');   % load data from workspace 
                sweep_length = evalin('base','sweep_length');   % load data from workspace 
                pre_trig = evalin('base','pre_trig');   % load data from workspace 
                N = evalin('base','N');   % load data from workspace 
                app.xaxis = linspace(-pre_trig,sweep_length-pre_trig, N); 

                app.sweep = 1; 
                app.SweepSpinner.Value = app.sweep;  

                app.step_index = evalin('base', 'step_index');  % load foot placement from workspace 
                app.fcn_indexes()

                app.fcn_updatePlots()               % plot data 
            end 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fprintf('\n     Gui Closed without exporting data \n')
            fprintf('\n     Press any key to continue...\n');
            delete(app)
        end

        % Value changed function: SweepSpinner
        function SweepSpinnerValueChanged(app, event)
            value = app.SweepSpinner.Value;

            switchAllowed = true;
            for i = [1,3,5]
                y = app.data{app.proto,app.FSR}(app.sweep, app.edges(i)+10);
                if y < 5
                    switchAllowed = false; 
                    errorMsg = "USER ERROR. A rise position has not been position at a rise";
                end
            end

            if or(~(issorted(app.edges(1:6))), ~(length(unique(app.edges(1:6))==6)))
                switchAllowed = false; 
                errorMsg = "USER ERROR. A edge position has been chosen twice";
            end


            if switchAllowed
                if size(app.data{app.proto,app.ANG},1) < value
                    app.SweepSpinner.Value = size(app.data{app.proto,app.ANG},1); 
                elseif 1 > value 
                    app.SweepSpinner.Value = 1; 
                else % new sweep 
                    app.sweep = app.SweepSpinner.Value;      % update sweep num
                    app.fcn_indexes(); 
                    app.fcn_updatePlots()               % plot data
                end
            else % error 
                app.SweepSpinner.Value = app.sweep;
                errordlg(errorMsg,'User Error');
            end
        end

        % Value changed function: RiseSpinner2
        function RiseSpinner2ValueChanged(app, event)
        if app.RiseSpinner2.Value > app.edgesNum(1)
            fcn_updateNumbersAllowed(app, 1, 1)
        else 
            fcn_updateNumbersAllowed(app, 1, -1)
        end
        end

        % Value changed function: FallSpinner2
        function FallSpinner2ValueChanged(app, event)
        if app.FallSpinner2.Value > app.edgesNum(2)
            fcn_updateNumbersAllowed(app, 2, 1)
        else 
            fcn_updateNumbersAllowed(app, 2, -1)
        end
        end

        % Value changed function: RiseSpinner4
        function RiseSpinner4ValueChanged(app, event)
         if app.RiseSpinner4.Value > app.edgesNum(3)
            fcn_updateNumbersAllowed(app, 3, 1)
        else 
            fcn_updateNumbersAllowed(app, 3, -1)
         end  
        end

        % Value changed function: RiseSpinner6
        function RiseSpinner6ValueChanged(app, event)
            if app.RiseSpinner6.Value > app.edgesNum(5)
                fcn_updateNumbersAllowed(app, 5, 1)
            else 
                fcn_updateNumbersAllowed(app, 5, -1)
            end
        end

        % Value changed function: FallSpinner4
        function FallSpinner4ValueChanged(app, event)
            if app.FallSpinner4.Value > app.edgesNum(4)
                fcn_updateNumbersAllowed(app, 4, 1)
            else 
                fcn_updateNumbersAllowed(app, 4, -1)
            end  
        end

        % Value changed function: FallSpinner6
        function FallSpinner6ValueChanged(app, event)
            if app.FallSpinner6.Value > app.edgesNum(6)
                fcn_updateNumbersAllowed(app, 6, 1)
            else 
                fcn_updateNumbersAllowed(app, 6, -1)
            end 
        end

        % Value changed function: RiseSpinner7
        function RiseSpinner7ValueChanged(app, event)
            if app.RiseSpinner7.Value > app.edgesNum(7)
                fcn_updateNumbersAllowed(app, 7, 1)
            else 
                fcn_updateNumbersAllowed(app, 7, -1)
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            assignin('base','offset',app.step_index)
            fprintf('\n     Exported data\n');
            fprintf('\n     Press any key to continue...\n');

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Step2Label = uilabel(app.UIFigure);
            app.Step2Label.Text = 'Step 2';
            app.Step4Label = uilabel(app.UIFigure);
            app.Step4Label.Text = 'Step 4';
            app.Step6Label = uilabel(app.UIFigure);
            app.Step6Label.Text = 'Step 6';
            app.Step7Label = uilabel(app.UIFigure);
            app.Step7Label.Text = 'Step 7';
            app.ProtocolEditFieldLabel = uilabel(app.UIFigure);
            app.ProtocolEditFieldLabel.Text = 'Protocol';
            app.ProtocolEditField = uieditfield(app.UIFigure, 'text');
            app.ProtocolEditField.Value = 'CTL';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.RiseSpinnerLabel = uilabel(app.UIFigure);
            app.RiseSpinnerLabel.Text = 'Rise';
            app.RiseSpinner2 = uispinner(app.UIFigure);
            app.RiseSpinner2.ValueChangedFcn = createCallbackFcn(app, @RiseSpinner2ValueChanged, true);
            app.FallSpinnerLabel = uilabel(app.UIFigure);
            app.FallSpinnerLabel.Text = 'Fall';
            app.FallSpinner2 = uispinner(app.UIFigure);
            app.FallSpinner2.ValueChangedFcn = createCallbackFcn(app, @FallSpinner2ValueChanged, true);
            app.RiseSpinner_2Label = uilabel(app.UIFigure);
            app.RiseSpinner_2Label.Text = 'Rise';
            app.RiseSpinner4 = uispinner(app.UIFigure);
            app.RiseSpinner4.ValueChangedFcn = createCallbackFcn(app, @RiseSpinner4ValueChanged, true);
            app.FallSpinner_2Label = uilabel(app.UIFigure);
            app.FallSpinner_2Label.Text = 'Fall';
            app.FallSpinner4 = uispinner(app.UIFigure);
            app.FallSpinner4.ValueChangedFcn = createCallbackFcn(app, @FallSpinner4ValueChanged, true);
            app.RiseSpinner_3Label = uilabel(app.UIFigure);
            app.RiseSpinner_3Label.Text = 'Rise';
            app.RiseSpinner6 = uispinner(app.UIFigure);
            app.RiseSpinner6.ValueChangedFcn = createCallbackFcn(app, @RiseSpinner6ValueChanged, true);
            app.FallSpinner_3Label = uilabel(app.UIFigure);
            app.FallSpinner_3Label.Text = 'Fall';
            app.FallSpinner6 = uispinner(app.UIFigure);
            app.FallSpinner6.ValueChangedFcn = createCallbackFcn(app, @FallSpinner6ValueChanged, true);
            app.RiseSpinner_4Label = uilabel(app.UIFigure);
            app.RiseSpinner_4Label.Text = 'Rise';
            app.RiseSpinner7 = uispinner(app.UIFigure);
            app.RiseSpinner7.ValueChangedFcn = createCallbackFcn(app, @RiseSpinner7ValueChanged, true);
            app.SweepSpinnerLabel = uilabel(app.UIFigure);
            app.SweepSpinnerLabel.Text = 'Sweep';
            app.SweepSpinner = uispinner(app.UIFigure);
            app.SweepSpinner.ValueChangedFcn = createCallbackFcn(app, @SweepSpinnerValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'method(2).png');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.Step4Label.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = findStepOnFSR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end