classdef readjustFSR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ProtocolEditField       matlab.ui.control.EditField
        ProtocolEditFieldLabel  matlab.ui.control.Label
        SweepSpinner            matlab.ui.control.Spinner
        SweepSpinnerLabel       matlab.ui.control.Label
        ExportButton            matlab.ui.control.Button
        LoadButton              matlab.ui.control.Button
        EditField_step6         matlab.ui.control.NumericEditField
        Rise6Label              matlab.ui.control.Label
        Slider_step6            matlab.ui.control.Slider
        EditField_step4         matlab.ui.control.NumericEditField
        Rise4Label              matlab.ui.control.Label
        EditField_step2         matlab.ui.control.NumericEditField
        Rise2Label              matlab.ui.control.Label
        Slider_step4            matlab.ui.control.Slider
        Slider_step2            matlab.ui.control.Slider
        UIAxes_step4            matlab.ui.control.UIAxes
        UIAxes_step2            matlab.ui.control.UIAxes
        UIAxes_step6            matlab.ui.control.UIAxes
        UIAxes_all              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % Description
        ANG % = 3
        FSR % = 4
        proto % 
        step_index % Description
        sweep % Description
        rise2
        fall2
        rise4
        fall4
        rise6
        fall6
        xaxis
    end
    
    methods (Access = private)
        
        function results = fcn_updatePlots(app)
            app.fcn_updateIndexs()

            % plot properties 
            color_rise = "blue"; 
            color_fall = "red"; 
            color_FSR = "black";
            linewidthRF = 2; 
            linestyleRF = '--'; 
            markerRF = '.'; 

            win_size_after = 900; 
            win_size_before = 300; 

            % step 2
            window = app.rise2-win_size_before:app.rise2+win_size_after; 
            yyaxis(app.UIAxes_step2,'left')
            hold(app.UIAxes_step2, "off") % Clear window
            plot(app.UIAxes_step2, app.xaxis(window), app.data{app.proto,app.ANG}(app.sweep, window)) % plt ankel
            hold(app.UIAxes_step2, "on") 
            YL(1) = min(app.data{app.proto,app.ANG}(app.sweep, window)); 
            YL(2) = max(app.data{app.proto,app.ANG}(app.sweep, window));
            plot(app.UIAxes_step2, [app.xaxis(app.rise2), app.xaxis(app.rise2)], [YL(1) YL(2)], "color", color_rise, 'LineWidth',linewidthRF) % plot the rise phase
            
            yyaxis(app.UIAxes_step2,'right')
            hold(app.UIAxes_step2, "off")
            plot(app.UIAxes_step2, app.xaxis(window), app.data{app.proto,app.FSR}(app.sweep,window),"color", color_FSR)
            axis(app.UIAxes_step2, 'padded')

            % step 4
            window = app.rise4-win_size_before:app.rise4+win_size_after; 
            yyaxis(app.UIAxes_step4,'left')
            hold(app.UIAxes_step4, "off")
            plot(app.UIAxes_step4, app.xaxis(window), app.data{app.proto,app.ANG}(app.sweep, window))
            hold(app.UIAxes_step4, "on")
            YL(1) = min(app.data{app.proto,app.ANG}(app.sweep, window));
            YL(2) = max(app.data{app.proto,app.ANG}(app.sweep, window));
            plot(app.UIAxes_step4, [app.xaxis(app.rise4), app.xaxis(app.rise4)], [YL(1) YL(2)], "color", color_rise, 'LineWidth',linewidthRF)
            
            yyaxis(app.UIAxes_step4,'right')
            hold(app.UIAxes_step4, "off")
            plot(app.UIAxes_step4, app.xaxis(window), app.data{app.proto,app.FSR}(app.sweep,window),"color", color_FSR)
            axis(app.UIAxes_step4, 'padded')

            % step 6
            window = app.rise6-win_size_before:app.rise6+win_size_after; 
            yyaxis(app.UIAxes_step6,'left')
            hold(app.UIAxes_step6, "off")
            plot(app.UIAxes_step6, app.xaxis(window), app.data{app.proto,app.ANG}(app.sweep, window))
            hold(app.UIAxes_step6, "on")
            YL(1) = min(app.data{app.proto,app.ANG}(app.sweep, window));
            YL(2) = max(app.data{app.proto,app.ANG}(app.sweep, window));
            plot(app.UIAxes_step6, [app.xaxis(app.rise6), app.xaxis(app.rise6)], [YL(1) YL(2)], "color", color_rise, 'LineWidth',linewidthRF)
            
            yyaxis(app.UIAxes_step6,'right')
            hold(app.UIAxes_step6, "off")
            plot(app.UIAxes_step6, app.xaxis(window), app.data{app.proto,app.FSR}(app.sweep,window),"color", color_FSR)
            axis(app.UIAxes_step6, 'padded')

            % all steps 
            yyaxis(app.UIAxes_all,'left')
            hold(app.UIAxes_all, "off")
            plot(app.UIAxes_all, app.xaxis, app.data{app.proto,app.ANG}(app.sweep,:))
            hold(app.UIAxes_all, "on")
            YL(1) = min(app.data{app.proto,app.ANG}(app.sweep, :));
            YL(2) = max(app.data{app.proto,app.ANG}(app.sweep, :));
            text(app.UIAxes_all, app.xaxis(app.rise2),YL(2)-1,"Step 2")
            text(app.UIAxes_all, app.xaxis(app.rise4),YL(2)-1,"Step 4")
            text(app.UIAxes_all, app.xaxis(app.rise6),YL(2)-1,"Step 6")


            plot(app.UIAxes_all, [app.xaxis(app.rise2), app.xaxis(app.rise2)], [YL(1) YL(2)], "color", color_rise, 'LineWidth', linewidthRF, 'LineStyle',linestyleRF, 'Marker',markerRF)
            plot(app.UIAxes_all, [app.xaxis(app.rise4), app.xaxis(app.rise4)], [YL(1) YL(2)], "color", color_rise, 'LineWidth', linewidthRF, 'LineStyle',linestyleRF, 'Marker',markerRF)
            plot(app.UIAxes_all, [app.xaxis(app.rise6), app.xaxis(app.rise6)], [YL(1) YL(2)], "color", color_rise, 'LineWidth', linewidthRF, 'LineStyle',linestyleRF, 'Marker',markerRF)
            plot(app.UIAxes_all, [app.xaxis(app.fall2), app.xaxis(app.fall2)], [YL(1) YL(2)], "color", color_fall, 'LineWidth', linewidthRF, 'LineStyle',linestyleRF, 'Marker',markerRF)
            plot(app.UIAxes_all, [app.xaxis(app.fall4), app.xaxis(app.fall4)], [YL(1) YL(2)], "color", color_fall, 'LineWidth', linewidthRF, 'LineStyle',linestyleRF, 'Marker',markerRF)
            plot(app.UIAxes_all, [app.xaxis(app.fall6), app.xaxis(app.fall6)], [YL(1) YL(2)], "color", color_fall, 'LineWidth', linewidthRF, 'LineStyle',linestyleRF, 'Marker',markerRF)

            yyaxis(app.UIAxes_all,'right')
            hold(app.UIAxes_all, "off")
            plot(app.UIAxes_all, app.xaxis, app.data{app.proto,app.FSR}(app.sweep,:), "color", color_FSR)
            axis(app.UIAxes_all, 'padded')

        end
        
        function results = fcn_updateIndexs(app)
            % updates field values:
            app.EditField_step2.Value = app.xaxis(app.rise2); 
            app.EditField_step4.Value = app.xaxis(app.rise4);
            app.EditField_step6.Value = app.xaxis(app.rise6);
        end
        
        function results = fcn_loadRise(app)
            % Update step_index to set values.
            
            % update step 2
            [rise, fall] = func_find_edge(2); 
            app.rise2 = app.step_index{app.proto}(app.sweep,rise) ; 
            app.fall2 = app.step_index{app.proto}(app.sweep,fall) ; 

            % update step 4
            [rise, fall] = func_find_edge(4); 
            app.rise4 = app.step_index{app.proto}(app.sweep,rise); 
            app.fall4 = app.step_index{app.proto}(app.sweep,fall); 

            % update step 6
            [rise, fall] = func_find_edge(6); 
            app.rise6 = app.step_index{app.proto}(app.sweep,rise); 
            app.fall6 = app.step_index{app.proto}(app.sweep,fall); 
        end
        
        function results = fcn_saveRise(app)
            [rise] = func_find_edge(2); 
            app.step_index{app.proto}(app.sweep,rise) = app.rise2; 
            % update step 4
            [rise] = func_find_edge(4); 
            app.step_index{app.proto}(app.sweep,rise) = app.rise4; 
            % update step 6
            [rise] = func_find_edge(6); 
            app.step_index{app.proto}(app.sweep,rise) = app.rise6; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.ANG = 3; app.FSR = 4; app.sweep = 1; 

            correct_protocol_input = true; 
            switch app.ProtocolEditField.Value
                case "CTL"
                    app.proto = 1; 
                case "VER"
                    app.proto = 2; 
                case "HOR"
                    app.proto = 3;
                otherwise
                    opts = struct('WindowStyle','modal','Interpreter','tex');
                    errordlg('Unknown input in protocol text field. Valuable options: [CTL, VER, HOR]','User Error', opts);
                    correct_protocol_input = false; 
            end 
            
            if correct_protocol_input == true
                app.data = evalin('base','data');   % load data from workspace 
                sweep_length = evalin('base','sweep_length');   % load data from workspace 
                pre_trig = evalin('base','pre_trig');   % load data from workspace 
                N = evalin('base','N');   % load data from workspace 
                app.xaxis = linspace(-pre_trig,sweep_length-pre_trig, N); 
                app.step_index = evalin('base', 'step_index');  % load foot placement from workspace 
                app.fcn_loadRise()                  % load new rise values
                app.fcn_updatePlots()               % plot data 
            end 
            
        end

        % Value changed function: SweepSpinner
        function SweepSpinnerValueChanged(app, event)
            value = app.SweepSpinner.Value;

            if size(app.data{app.proto,app.ANG},1) < value
                app.SweepSpinner.Value = size(app.data{app.proto,app.ANG},1); 
            elseif 1 > value 
                app.SweepSpinner.Value = 1; 
            else % new sweep 
                app.fcn_saveRise()                  % save rise values 
                app.sweep = app.SweepSpinner.Value; % update sweep num
                app.fcn_loadRise()                  % load new rise values
                app.fcn_updatePlots()               % plot data
            end


        end

        % Value changed function: EditField_step2
        function EditField_step2ValueChanged(app, event)
            value = app.EditField_step2.Value;
            value = find(value == app.xaxis ); 
            if or(value <= 0, value >= 20000-app.win_size ) 
                errordlg("error")
                app.EditField_step2.Value = app.xaxis(app.rise2); 
            else
                app.rise2 = value; 
                app.fcn_updatePlots()
            end

        end

        % Value changed function: Slider_step2
        function Slider_step2ValueChanged(app, event)
            app.rise2 = ceil(app.rise2 + app.Slider_step2.Value);
            app.Slider_step2.Value = 0; 
            
            app.fcn_updatePlots()

        end

        % Value changed function: Slider_step4
        function Slider_step4ValueChanged(app, event)
            app.rise4 = ceil(app.rise4 + app.Slider_step4.Value);
            app.Slider_step4.Value = 0; 
           
            app.fcn_updatePlots()
            
        end

        % Value changed function: Slider_step6
        function Slider_step6ValueChanged(app, event)
            app.rise6 = ceil(app.rise6 + app.Slider_step6.Value);
            app.Slider_step6.Value = 0; 
           
            app.fcn_updatePlots()            
        end

        % Value changed function: EditField_step4
        function EditField_step4ValueChanged(app, event)
            value = app.EditField_step4.Value;
            value = find(value == app.xaxis ); 

            if or(value <= 0, value >= 20000-app.win_size ) 
                errordlg("error")
                app.EditField_step4.Value = app.xaxis(app.rise4); 
            else
                app.rise4 = value; 
                app.fcn_updatePlots()
            end            

        end

        % Value changed function: EditField_step6
        function EditField_step6ValueChanged(app, event)
            value = app.EditField_step6.Value;
            value = find(value == app.xaxis );
            if or(value <= 0, value >= 20000-app.win_size ) 
                errordlg("error")
                app.EditField_step6.Value = app.xaxis(app.rise6); 
            else
                app.rise6 = value; 
                app.fcn_updatePlots()
            end            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            assignin('base','offset',app.step_index)
            fprintf('\n     Exported data\n');
            fprintf('\n     Press any key to continue...\n');

            delete(app)
        end

        % Value changed function: ProtocolEditField
        function ProtocolEditFieldValueChanged(app, event)
            correct_protocol_input = true; 

            switch app.ProtocolEditField.Value
                case "CTL" % control
                    app.proto = 1; 
                case "VER" % vertical perturbation
                    app.proto = 2; 
                case "HOR" % horizontal perturbation
                    app.proto = 3;
                otherwise
                    opts = struct('WindowStyle','modal','Interpreter','tex');
                    errordlg('Unknown input in protocol text field. Valuable options: [CTL, VER, HOR]','User Error', opts);
                    correct_protocol_input = false; 
            end 
            
            if correct_protocol_input == true
                app.sweep = 1; 
                app.SweepSpinner.Value = app.sweep;
                app.fcn_loadRise()                  % load new rise values
                app.fcn_updatePlots()               % plot data 
                
            else
                switch app.proto
                    case 1
                        app.ProtocolEditField.Value = "CTL";
                    case 2
                        app.ProtocolEditField.Value = "VER";
                    case 3
                        app.ProtocolEditField.Value = "HOR";
                end                       
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fprintf('\n     Gui Closed without exporting data \n')
            fprintf('\n     Press any key to continue...\n');
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_all = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_all, 'time [ms]')
            ylabel(app.UIAxes_all, 'Y')
            zlabel(app.UIAxes_all, 'Z')
            app.UIAxes_step6 = uiaxes(app.UIFigure);
            title(app.UIAxes_step6, 'Step 6')
            xlabel(app.UIAxes_step6, 'time [ms]')
            ylabel(app.UIAxes_step6, 'Y')
            zlabel(app.UIAxes_step6, 'Z')
            app.UIAxes_step2 = uiaxes(app.UIFigure);
            title(app.UIAxes_step2, 'Step 2')
            xlabel(app.UIAxes_step2, 'time [ms]')
            ylabel(app.UIAxes_step2, 'Y')
            zlabel(app.UIAxes_step2, 'Z')
            app.UIAxes_step4 = uiaxes(app.UIFigure);
            title(app.UIAxes_step4, 'Step 4')
            xlabel(app.UIAxes_step4, 'time [ms]')
            ylabel(app.UIAxes_step4, 'Y')
            zlabel(app.UIAxes_step4, 'Z')
            app.Slider_step2 = uislider(app.UIFigure);
            app.Slider_step2.Limits = [-80 80];
            app.Slider_step2.ValueChangedFcn = createCallbackFcn(app, @Slider_step2ValueChanged, true);
            app.Slider_step4 = uislider(app.UIFigure);
            app.Slider_step4.Limits = [-80 80];
            app.Slider_step4.ValueChangedFcn = createCallbackFcn(app, @Slider_step4ValueChanged, true);
            app.Rise2Label = uilabel(app.UIFigure);
            app.Rise2Label.Text = 'Rise 2:';
            app.EditField_step2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_step2.ValueDisplayFormat = '%.0f';
            app.EditField_step2.ValueChangedFcn = createCallbackFcn(app, @EditField_step2ValueChanged, true);
            app.Rise4Label = uilabel(app.UIFigure);
            app.Rise4Label.Text = 'Rise 4';
            app.EditField_step4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_step4.ValueDisplayFormat = '%.0f';
            app.EditField_step4.ValueChangedFcn = createCallbackFcn(app, @EditField_step4ValueChanged, true);
            app.Slider_step6 = uislider(app.UIFigure);
            app.Slider_step6.Limits = [-80 80];
            app.Slider_step6.ValueChangedFcn = createCallbackFcn(app, @Slider_step6ValueChanged, true);
            app.Rise6Label = uilabel(app.UIFigure);
            app.Rise6Label.Text = 'Rise 6';
            app.EditField_step6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_step6.ValueDisplayFormat = '%.0f';
            app.EditField_step6.ValueChangedFcn = createCallbackFcn(app, @EditField_step6ValueChanged, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SweepSpinnerLabel = uilabel(app.UIFigure);
            app.SweepSpinnerLabel.Text = 'Sweep';
            app.SweepSpinner = uispinner(app.UIFigure);
            app.SweepSpinner.ValueChangedFcn = createCallbackFcn(app, @SweepSpinnerValueChanged, true);
            app.SweepSpinner.Value = 1;
            app.ProtocolEditFieldLabel = uilabel(app.UIFigure);
            app.ProtocolEditFieldLabel.Text = 'Protocol';
            app.ProtocolEditField = uieditfield(app.UIFigure, 'text');
            app.ProtocolEditField.ValueChangedFcn = createCallbackFcn(app, @ProtocolEditFieldValueChanged, true);
            app.ProtocolEditField.Value = 'CTL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = readjustFSR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end